classdef channesimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        PathLossExponentEditField  matlab.ui.control.NumericEditField
        Label_7                    matlab.ui.control.Label
        PathLossEditField          matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        ShadowFadingEditField      matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        ModulationDropDown         matlab.ui.control.DropDown
        Label_4                    matlab.ui.control.Label
        TogglePlotButton           matlab.ui.control.Button
        omegaEditField             matlab.ui.control.NumericEditField
        omegaEditFieldLabel        matlab.ui.control.Label
        ResetPlotButton            matlab.ui.control.Button
        SeedButton                 matlab.ui.control.Button
        NakagamiMEditField         matlab.ui.control.NumericEditField
        mEditFieldLabel            matlab.ui.control.Label
        BERValueLabel              matlab.ui.control.NumericEditField
        BEREditFieldLabel          matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        ChannelTypeDropDown        matlab.ui.control.DropDown
        Label_3                    matlab.ui.control.Label
        SNREditField               matlab.ui.control.NumericEditField
        SNREditFieldLabel          matlab.ui.control.Label
        NumSamplesEditField        matlab.ui.control.NumericEditField
        EditFieldLabel_3           matlab.ui.control.Label
        SampleTimeEditField        matlab.ui.control.NumericEditField
        EditFieldLabel_2           matlab.ui.control.Label
        DopplerShiftEditField      matlab.ui.control.NumericEditField
        EditFieldLabel             matlab.ui.control.Label
        LOSStrengthEditField       matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        NumPathsEditField          matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        SNRBERAxes                 matlab.ui.control.UIAxes
        OutputSignalAxes           matlab.ui.control.UIAxes
        InputSignalAxes            matlab.ui.control.UIAxes
    end

   
    properties (Access = private)
        rngSeed               double  % 随机种子
        isPaused              logical % 是否暂停
        isStopped             logical % 是否停止
        plotFullCurve         logical = false % 是否显示完整图像
        keepPlot              logical = false % 保持图像
    end

    methods (Access = private)

        function nakagamiChan =nakagami_channel(~,m, omega, num_samples, sampleRate, dopplerShift)

            % 生成 Nakagami 分布的随机变量
            nakagamiGains = sqrt(gamrnd(m, omega / m, num_samples, 1));

            % 生成时间向量
            t = (0:num_samples-1) / sampleRate;

            % 加入多普勒频移
            dopplerEffect = exp(1i * 2 * pi * dopplerShift * t);

            % 将多普勒效应应用到 Nakagami 信道增益
            nakagamiChan = nakagamiGains .* dopplerEffect.';

            % 归一化增益
            nakagamiChan = nakagamiChan / sqrt(mean(abs(nakagamiChan).^2));
        end

        % 计算误码率函数
        function ber = calculate_ber(~, originalBits, demodBits)
            % 确保两个比特流长度一致
            minLen = min(length(originalBits), length(demodBits));
            originalBits = originalBits(1:minLen);
            demodBits = demodBits(1:minLen);

            [numErrors, ~] = biterr(originalBits, demodBits);
            ber = numErrors / minLen;
        end
        function demodData = demodulateSignal(~, noisySignal, modulationType)
            % 根据选择的调制方式进行解调
            switch modulationType
                case 'QPSK调制'
                    demodData = pskdemod(noisySignal, 4, pi/4, 'gray', 'OutputType', 'bit');
                case '16QAM调制'
                    demodData = qamdemod(noisySignal, 16, 'OutputType', 'bit');
                case 'BPSK调制'
                    demodData = pskdemod(noisySignal, 2);
                otherwise
                    error('无效的调制方式');
            end
        end

        function noisySignal = addNoiseWithPathLoss(~,signal, snr, pathLossExponent, shadowFading,pathDistance)
            % 计算信号功率
            signalPower = mean(abs(signal).^2);

            if pathDistance ~= 0 || shadowFading ~= 0
                % 对数距离路径损耗计算
                d0 = 1; % 参考距离，通常为 1 米
                pathDistance = max(pathDistance, d0); % 确保 pathDistance 不小于 d0
                pathLoss = 10 * pathLossExponent * log10(pathDistance / d0);

                % 计算总损耗
                totalLoss = pathLoss + shadowFading * randn;

                fprintf('Path Loss: %.2f dB\n', pathLoss);
                fprintf('Shadow Fading: %.2f dB\n', shadowFading * randn);
                fprintf('Total Loss: %.2f dB\n', totalLoss);
                fprintf('Path Loss Exponent: %.2f\n', pathLossExponent);
                % 应用路径损耗和阴影衰落
                signalWithLoss = signal .* 10.^(-totalLoss/20);

                % 计算噪声功率
                noisePower = signalPower / (10^(snr / 10));

                % 添加噪声
                noisySignal = signalWithLoss + sqrt(noisePower/2) * (randn(size(signal)) + 1i * randn(size(signal)));
            else
                % 使用awgn函数添加噪声
                noisySignal = awgn(signal, snr, 'measured');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed2(app, event)

            app.isPaused = false;
            app.isStopped = false;

            % 获取用户输入的参数
            numPaths = app.NumPathsEditField.Value;
            losStrength = app.LOSStrengthEditField.Value;
            dopplerShift = app.DopplerShiftEditField.Value;
            sampleTime = app.SampleTimeEditField.Value;
            numSamples = app.NumSamplesEditField.Value;
            channelType = app.ChannelTypeDropDown.Value;
            nakagamiM = app.NakagamiMEditField.Value;
            snr = app.SNREditField.Value;
            sampleRate = 100000; 
            omega = app.omegaEditField.Value;
            modulationType = app.ModulationDropDown.Value; 
            shadowFading = app.ShadowFadingEditField.Value; 
            pathDistance = app.PathLossEditField.Value;
            pathLossExponent = app.PathLossExponentEditField.Value; 

            % 输入参数检查
            if isempty(numPaths) || numPaths <= 0
                uialert(app.UIFigure, '多径分量数目必须为正数', '输入错误');
                return;
            end
            if isempty(losStrength) || losStrength < 0
                uialert(app.UIFigure, '直视路径强度必须为非负数', '输入错误');
                return;
            end
            if isempty(dopplerShift) || dopplerShift < 0
                uialert(app.UIFigure, '多普勒频移必须为非负数', '输入错误');
                return;
            end
            if isempty(sampleTime) || sampleTime <= 0
                uialert(app.UIFigure, '采样时间必须为正数', '输入错误');
                return;
            end
            if isempty(numSamples) || numSamples <= 0
                uialert(app.UIFigure, '信号样本数必须为正数', '输入错误');
                return;
            end
            if isempty(nakagamiM) || nakagamiM <= 0
                uialert(app.UIFigure, 'Nakagami m 因子必须为正数', '输入错误');
                return;
            end
            if isempty(omega) || omega<= 0
                uialert(app.UIFigure, 'omega 因子必须为正数', '输入错误');
                return;
            end

            % 确保多普勒频移满足条件
            maxDopplerShift = 1 / (10 * sampleTime);
            if dopplerShift > maxDopplerShift
                uialert(app.UIFigure, sprintf('多普勒频移必须小于等于 %.2f Hz', maxDopplerShift), '参数错误');
                return;
            end

            % 设置随机种子
            rng(app.rngSeed);

            switch modulationType
                case 'QPSK调制'
                    data = randi([0 1], numSamples * 2, 1); % QPSK每个符号需要2个比特
                case '16QAM调制'
                    data = randi([0 1], numSamples * 4, 1); % 16QAM每个符号需要4个比特
                case 'BPSK调制'
                    data = randi([0 1], numSamples, 1); % BPSK每个符号需要1个比特
                otherwise
                    uialert(app.UIFigure, '请选择一个有效的调制方式');
                    return;
            end

            % 调制选择
            switch modulationType
                case 'QPSK调制'
                    modData = pskmod(data, 4, pi/4, 'gray', 'InputType', 'bit');
                case '16QAM调制'
                    modData = qammod(data, 16, 'InputType', 'bit');
                case 'BPSK调制'
                    modData = pskmod(data, 2);
                otherwise
                    uialert(app.UIFigure, '请选择一个有效的调制方式');
                    return;
            end

            function perform_simulation()
                switch channelType
                    case 'Rayleigh信道'
                        rayleighChan = comm.RayleighChannel('SampleRate', sampleRate, ...
                            'MaximumDopplerShift', dopplerShift, 'PathDelays', (0:numPaths-1) * sampleTime, ...
                            'AveragePathGains', zeros(1, numPaths));
                        channelSignal = rayleighChan(modData);
                        channelLabel = 'Rayleigh信道';
                        plotColor = 'r';
                    case 'Rician信道'
                        ricianChan = comm.RicianChannel('SampleRate', sampleRate, ...
                            'MaximumDopplerShift', dopplerShift, 'KFactor', losStrength, ...
                            'PathDelays', (0:numPaths-1) * sampleTime, ...
                            'AveragePathGains', zeros(1, numPaths));
                        channelSignal = ricianChan(modData);
                        channelLabel = 'Rician信道';
                        plotColor = 'g';
                    case '高斯信道'
                        channelSignal = modData;
                        channelLabel = '高斯信道';
                        plotColor = 'b';
                    case 'Nakagami信道'
                        nakagamiGains = app.nakagami_channel(nakagamiM, omega, length(modData), sampleRate, dopplerShift);
                        channelSignal = modData .* nakagamiGains;
                        channelLabel = 'Nakagami信道';
                        plotColor = 'k';
                    otherwise
                        uialert(app.UIFigure, '请选择一个有效的信道类型', '输入错误');
                        return;
                end

                % 确保 modData 和 channelSignal 维度一致
                if length(modData) ~= length(channelSignal)
                    uialert(app.UIFigure, '调制信号和信道输出信号的长度不一致', '维度错误');
                    return;
                end

                % 调试输出
                disp(['信道类型: ', channelType]);
                disp(['SNR: ', num2str(snr)]);
                disp(['样本数: ', num2str(numSamples)]);

                % 添加噪声
                noisySignal = addNoiseWithPathLoss(app,channelSignal, snr, pathLossExponent, shadowFading, pathDistance);

                % 单点或完整曲线绘制
                if app.plotFullCurve
                    % 绘制完整的 SNR vs BER 曲线
                    snrValues = 0:1:15;
                    berValues = zeros(size(snrValues));
                    for idx = 1:length(snrValues)
                        currentSNR = snrValues(idx);
                        % 重新计算噪声功率以实现不同的SNR
                        adjustedNoisySignal = addNoiseWithPathLoss(app,channelSignal, currentSNR, pathLossExponent, shadowFading, pathDistance);
                        demodData = app.demodulateSignal(adjustedNoisySignal, modulationType);
                        berValues(idx) = app.calculate_ber(data, demodData);
                    end
                    hold(app.SNRBERAxes, 'on'); % 保持或清除之前的绘图
                    plot(app.SNRBERAxes, snrValues, berValues, '-o', 'Color', plotColor, 'DisplayName', channelLabel);
                else
                    % 绘制单点
                    demodData = app.demodulateSignal(noisySignal, modulationType);
                    ber = app.calculate_ber(data, demodData);
                    app.BERValueLabel.Value = ber;
                    hold(app.SNRBERAxes, 'on'); % 保持或清除之前的绘图
                    plot(app.SNRBERAxes, snr, ber, 'o', 'Color', plotColor, 'DisplayName', channelLabel);
                end

                legend(app.SNRBERAxes, 'show');
                title(app.SNRBERAxes, 'SNR vs BER');
                xlabel(app.SNRBERAxes, 'SNR (dB)');
                ylabel(app.SNRBERAxes, 'BER');

                % 绘制输入信号
                cla(app.InputSignalAxes);
                plot(app.InputSignalAxes, real(modData), 'LineWidth', 1.5);
                title(app.InputSignalAxes, '输入信号');
                xlabel(app.InputSignalAxes, '样本');
                ylabel(app.InputSignalAxes, '幅度');

                % 绘制输出信号
                cla(app.OutputSignalAxes);
                plot(app.OutputSignalAxes, real(noisySignal), 'LineWidth', 1.5);
                title(app.OutputSignalAxes, '输出信号');
                xlabel(app.OutputSignalAxes, '样本');
                ylabel(app.OutputSignalAxes, '幅度');
            end

            while ~app.isStopped
                if app.isPaused
                    pause(0.1);
                else
                    perform_simulation();
                    break;
                end
            end


        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.isPaused = ~app.isPaused; 
            if app.isPaused
                disp('仿真已暂停');
            else
                disp('仿真已继续');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStopped = true;
            app.isPaused = false;
            disp('仿真已停止');
        end

        % Button pushed function: SeedButton
        function SeedButtonPushed(app, event)
            app.rngSeed = randi([0, 10000]); % 随机生成一个种子
            uialert(app.UIFigure, sprintf('随机种子已设置为 %d', app.rngSeed), '随机种子设置');
        end

        % Button pushed function: ResetPlotButton
        function ResetPlotButtonPushed(app, event)
             cla(app.InputSignalAxes);
            cla(app.OutputSignalAxes);
            cla(app.SNRBERAxes);
            % 清空图例
            legend(app.SNRBERAxes, 'off');
        end

        % Button pushed function: TogglePlotButton
        function TogglePlotButtonPushed(app, event)
    app.plotFullCurve = ~app.plotFullCurve; 
    if app.plotFullCurve
        disp('切换到显示完整图像模式');
    else
        disp('切换到显示单点模式');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputSignalAxes = uiaxes(app.GridLayout);
            title(app.InputSignalAxes, '输入信号')
            app.OutputSignalAxes = uiaxes(app.GridLayout);
            title(app.OutputSignalAxes, '输出信号')
            app.SNRBERAxes = uiaxes(app.GridLayout);
            title(app.SNRBERAxes, '误码率（BER））')
            app.Label = uilabel(app.GridLayout);
            app.Label.Tag = 'NumPathsEdit';
            app.Label.Text = '多径分量数目';
            app.NumPathsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumPathsEditField.Tag = 'NumPathsEditField';
            app.NumPathsEditField.Tooltip = {'NumPathsEdit'};
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '直视路径强度(K)';
            app.LOSStrengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Text = '多普勒频移';
            app.DopplerShiftEditField = uieditfield(app.GridLayout, 'numeric');
            app.EditFieldLabel_2 = uilabel(app.GridLayout);
            app.EditFieldLabel_2.Text = '采样时间';
            app.SampleTimeEditField = uieditfield(app.GridLayout, 'numeric');
            app.EditFieldLabel_3 = uilabel(app.GridLayout);
            app.EditFieldLabel_3.Text = '信号样本数';
            app.NumSamplesEditField = uieditfield(app.GridLayout, 'numeric');
            app.SNREditFieldLabel = uilabel(app.GridLayout);
            app.SNREditFieldLabel.Text = '信噪比 (SNR)';
            app.SNREditField = uieditfield(app.GridLayout, 'numeric');
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '信道类型';
            app.ChannelTypeDropDown = uidropdown(app.GridLayout);
            app.ChannelTypeDropDown.Items = {'Rayleigh信道', 'Rician信道', '高斯信道', 'Nakagami信道'};
            app.ChannelTypeDropDown.Tag = 'ChannelTypeDropDown';
            app.ChannelTypeDropDown.Value = 'Rayleigh信道';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed2, true);
            app.StartButton.Text = '启动仿真';
            app.PauseButton = uibutton(app.GridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = '暂停仿真';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '停止仿真';
            app.BEREditFieldLabel = uilabel(app.GridLayout);
            app.BEREditFieldLabel.Text = '误码率 (BER)';
            app.BERValueLabel = uieditfield(app.GridLayout, 'numeric');
            app.mEditFieldLabel = uilabel(app.GridLayout);
            app.mEditFieldLabel.Text = 'm';
            app.NakagamiMEditField = uieditfield(app.GridLayout, 'numeric');
            app.SeedButton = uibutton(app.GridLayout, 'push');
            app.SeedButton.ButtonPushedFcn = createCallbackFcn(app, @SeedButtonPushed, true);
            app.SeedButton.Text = '种子控制';
            app.ResetPlotButton = uibutton(app.GridLayout, 'push');
            app.ResetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotButton.Text = '重置';
            app.omegaEditFieldLabel = uilabel(app.GridLayout);
            app.omegaEditFieldLabel.Text = 'omega';
            app.omegaEditField = uieditfield(app.GridLayout, 'numeric');
            app.TogglePlotButton = uibutton(app.GridLayout, 'push');
            app.TogglePlotButton.ButtonPushedFcn = createCallbackFcn(app, @TogglePlotButtonPushed, true);
            app.TogglePlotButton.Text = '切换图像/点';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '调制方式';
            app.ModulationDropDown = uidropdown(app.GridLayout);
            app.ModulationDropDown.Items = {'QPSK调制', '16QAM调制', 'BPSK调制'};
            app.ModulationDropDown.Value = 'QPSK调制';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '阴影衰落σ';
            app.ShadowFadingEditField = uieditfield(app.GridLayout, 'numeric');
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '路径距离';
            app.PathLossEditField = uieditfield(app.GridLayout, 'numeric');
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = '路径损耗参数';
            app.PathLossExponentEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channesimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end